classdef verifylengths < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label2            matlab.ui.control.Label
        Label             matlab.ui.control.Label
        NLabel            matlab.ui.control.Label
        Long3Button       matlab.ui.control.Button
        Justright2Button  matlab.ui.control.Button
        Short1Button      matlab.ui.control.Button
        breakdown         matlab.ui.control.UIAxes
        Cellim            matlab.ui.control.UIAxes
        lengthhist        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cells % structure of all cells.
        ci % Current cell.
        plotl % Description
    end
    
    methods (Access = private)
        
        function newcell(app)
            eds = -0.5:1:2.5;
            h = histcounts([app.cells.lencheck],eds,...
                'Normalization',"pdf");
            b = bar([0 1 2],h,0.75,'Parent',app.breakdown,'FaceColor','flat');
            b.CData = [0.5 0.5 0.9; 0.5 0.9 0.5; 0.9 0.5 0.5];
            app.breakdown.YLim = [0 1];
            eds = 1:0.5:15;
            cla(app.lengthhist);
            scells = [];
            gcells = [];
            lcells = [];
            for i = 1:numel(app.cells)
                if app.cells(i).lencheck == 0 
                    scells = [scells; app.cells(i).len];
                end
                if app.cells(i).lencheck == 1
                    gcells = [gcells; app.cells(i).len];
                end
                if app.cells(i).lencheck ==2
                    lcells = [lcells; app.cells(i).len];
                end
            end
            histogram(scells,eds,...
                'FaceColor','b','FaceAlpha',0.4,'Parent',app.lengthhist);
            histogram(gcells,eds,...
                'FaceColor','g','FaceAlpha',0.4,'Parent',app.lengthhist);
            histogram(lcells,eds,...
                'FaceColor','r','FaceAlpha',0.4,'Parent',app.lengthhist);
            
            
            app.NLabel.Text = char(sprintf('N = %d',numel([scells; gcells; lcells])));
            
            
            app.ci = randi(numel(app.cells));
            
            
            app.plotl.XData = app.cells(app.ci).pix(:,1);
            app.plotl.YData = app.cells(app.ci).pix(:,2);
            
            app.Cellim.XLim = [mean(app.cells(app.ci).pix(:,1))-50 ...
               mean(app.cells(app.ci).pix(:,1))+50];
            app.Cellim.YLim = [mean(app.cells(app.ci).pix(:,2))-50 ...
               mean(app.cells(app.ci).pix(:,2))+50];
            app.Label2.Text = sprintf('%1.2f',toc);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, l, maskout, sm, maxl, minl, th)
            app.Label.Text = sprintf('Smoothing = %1.2f\n Max length = %1.2f\n Min length = %1.2f\n Threshold = %1.2f', sm, maxl*0.133, minl*0.133,th);
            tic
            app.cells = findcells(l,maskout,sm,maxl,minl,th);
            for i = 1:numel(app.cells)
                dx = app.cells(i).pix(2:end,1)-app.cells(i).pix(1:end-1,1);
                dy = app.cells(i).pix(2:end,2)-app.cells(i).pix(1:end-1,2);
                dr = sqrt(dx.^2+dy.^2);
                app.cells(i).len = sum(dr)*0.133;
            end
            
            imagesc(app.Cellim,l);
            colormap(app.Cellim,"gray");
            hold(app.Cellim,'on');
            hold(app.lengthhist,'on');
            app.ci = randi(numel(app.cells));
            
            app.plotl = plot(app.Cellim,app.cells(app.ci).pix(:,1),...
                app.cells(app.ci).pix(:,2),'r','LineWidth',1);
            
            app.Cellim.XLim = [mean(app.cells(app.ci).pix(:,1))-50 ...
               mean(app.cells(app.ci).pix(:,1))+50];
            app.Cellim.YLim = [mean(app.cells(app.ci).pix(:,2))-50 ...
               mean(app.cells(app.ci).pix(:,2))+50];
            
        end

        % Button pushed function: Short1Button
        function shortbutton(app, event)
            app.cells(app.ci).lencheck = 0;
            app.newcell;
        end

        % Button pushed function: Justright2Button
        function justrightbutton(app, event)
            app.cells(app.ci).lencheck = 1;
            app.newcell;
        end

        % Button pushed function: Long3Button
        function longbutton(app, event)
            app.cells(app.ci).lencheck = 2;
            app.newcell;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case '1'
                    app.cells(app.ci).lencheck = 0;
                    app.newcell;
                case '2'
                    app.cells(app.ci).lencheck = 1;
                    app.newcell;
                case '3'
                    app.cells(app.ci).lencheck = 2;
                    app.newcell;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.lengthhist = uiaxes(app.UIFigure);
            title(app.lengthhist, 'Lengths')
            xlabel(app.lengthhist, 'Length({\mu}m)')
            ylabel(app.lengthhist, 'Count')
            app.Cellim = uiaxes(app.UIFigure);
            app.Cellim.XTick = [];
            app.Cellim.YTick = [];
            app.Cellim.Visible = 'off';
            app.breakdown = uiaxes(app.UIFigure);
            title(app.breakdown, 'Breakdown')
            ylabel(app.breakdown, 'Percentage')
            app.breakdown.XLim = [-1 3];
            app.breakdown.XTick = [0 1 2];
            app.breakdown.XTickLabel = {'''Short'''; '''Just right'''; '''Long'''};
            app.Short1Button = uibutton(app.UIFigure, 'push');
            app.Short1Button.ButtonPushedFcn = createCallbackFcn(app, @shortbutton, true);
            app.Short1Button.Text = 'Short (1)';
            app.Justright2Button = uibutton(app.UIFigure, 'push');
            app.Justright2Button.ButtonPushedFcn = createCallbackFcn(app, @justrightbutton, true);
            app.Justright2Button.Text = 'Just right (2)';
            app.Long3Button = uibutton(app.UIFigure, 'push');
            app.Long3Button.ButtonPushedFcn = createCallbackFcn(app, @longbutton, true);
            app.Long3Button.Text = 'Long (3)';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N = ';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifylengths(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end